classdef ListControl < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        GridLayout  matlab.ui.container.GridLayout
        EditField   matlab.ui.control.EditField
        Button      matlab.ui.control.Button
    end

    % Events with associated public callbacks
    events (HasCallbackProperty, NotifyAccess = private)
        ValueChanged
    end

    properties (Access = public)
        EditItemsFcn
        HasButton logical = true;
        Items (1,:) string = string.empty;
        ItemsData (1,:)
        ThemeChangedListener % Listener for theme changed event
        Value (1,:) % Value of component
    end
    
    
    % Discussion:
    % - Is Items and ItemsData needed? Seems redundant w.r.t Value.
    
    methods
        function set.Value(comp, value)

            comp.Value = value;
            comp.postSetValue()
        end

        function set.Items(comp, value)
            comp.Items = value;
            comp.postSetItems()
        end
               
        function set.ItemsData(comp, value)
            comp.ItemsData = value;
            comp.postSetItemsData()
        end
    end

    methods (Access = private)
        function postSetValue(comp)
            comp.ItemsData = comp.Value;
            if isempty(comp.ItemsData) || iscell(comp.ItemsData) && isempty(comp.ItemsData{1})
                comp.Items = "";
            else
                comp.Items = arrayfun(@(x) string(x), comp.ItemsData);
            end
        end

        function postSetItems(comp)
            comp.EditField.Value = strjoin(comp.Items, '; ');
        end
                
        function postSetItemsData(comp)
            % pass
        end
        
        function configureActionButtonAppearance(comp, baseColorStyle)
        % configureActionButtonAppearance - Set icon of button, respecting theme
            if nargin < 2
                baseColorStyle = comp.getBaseColorStyle;
            end

            editFieldColorFg = [0.40,0.40,0.40];
            editFieldColorBg = [0.97,0.97,0.97];

            switch baseColorStyle
                case 'light'
                    filename = 'edit';
                case 'dark'
                    filename = 'edit_dark';
                    editFieldColorBg = fliplightness(editFieldColorBg)+0.15;
                    editFieldColorFg = fliplightness(editFieldColorFg);
            end

            iconFilePath = om.internal.getIconPath(filename);
            comp.Button.Icon = iconFilePath;

            comp.EditField.FontColor = editFieldColorFg;
            comp.EditField.BackgroundColor = editFieldColorBg;
        end

        function configureButtonAppearanceIconButton(comp)
        %    Todo: Future integration using IconButton component
            pathToMLAPP = fileparts(mfilename('fullpath'));
            
            comp.Button.SVGSource = fullfile(pathToMLAPP, '001_-edit.svg');
            comp.Button.BackgroundAlpha_Hover = 0.3; % Show semi-transparent background on hover
            comp.Button.Color = '';
            
            comp.Button.Height = 21;
            comp.Button.Width = 21;
            comp.Button.Padding = 1;
            comp.Button.CornerRadius = 5;
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
        end

        function baseColorStyle = getBaseColorStyle(comp)
            hFigure = ancestor(comp, 'figure');
            if isprop(hFigure, 'Theme') && ~isempty(hFigure.Theme)
                baseColorStyle = hFigure.Theme.BaseColorStyle;
            else
                baseColorStyle = 'light';
            end
        end
    end
    
    methods (Access = private)
        function onParentPropertyChanged(comp, ~, evt)
            if strcmp(evt.Property, 'BackgroundColor')
                comp.updateBackgroundColor()
            end
        end
        function updateBackgroundColor(comp)
            if isa(comp.Parent, 'matlab.ui.Figure')
                backgroundColor = comp.Parent.Color;
            else
                if isprop(comp.Parent, 'BackgroundColor')
                    backgroundColor = comp.Parent.BackgroundColor;
                else
                    S = warning('off', 'backtrace');
                    warning('Could not detect background color.')
                    warning(S);
                end
            end
            comp.BackgroundColor = backgroundColor;
            comp.GridLayout.BackgroundColor = backgroundColor;
            comp.Button.BackgroundColor = backgroundColor;
        end

        function onThemeChanged(comp, ~, evt)
            comp.doUpdateTheme(evt.Theme.BaseColorStyle)
        end
        function doUpdateTheme(comp, baseColorStyle)
            comp.configureActionButtonAppearance(baseColorStyle)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function postSetupFcn(comp)
            comp.configureActionButtonAppearance()
                        
            comp.Button.Tooltip = 'Edit...';
            comp.Button.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ButtonPushed, true);
            
            addlistener(comp.Parent, 'BindablePropertyChanged', @comp.onParentPropertyChanged);
            updateBackgroundColor(comp)

            try
                comp.ThemeChangedListener = addlistener(comp, ...
                    'ThemeChanged', @comp.onThemeChanged);
            catch
                % pass
            end
            
            hFigure = ancestor(comp, 'figure');
            if isprop(hFigure, 'Theme') && ~isempty(hFigure.Theme)
                comp.doUpdateTheme(hFigure.Theme.BaseColorStyle)
            end
        end

        % Callback function
        function ButtonPushed(comp, event)
            if ~isempty(comp.EditItemsFcn)

                hFigure = ancestor(comp, 'figure');
                hProgressDialog = uiprogressdlg(hFigure, ...
                    "Indeterminate", "on", ...
                    "Message", sprintf( "Edit %s", comp.Tag ) );

                previousValue = comp.Value;

                % Need to pass some metainformation, like what types (more transparently)
                [items, itemsData] = comp.EditItemsFcn(comp.ItemsData);

                delete(hProgressDialog)

                if isempty(items) && isempty(itemsData)
                    return
                end

                comp.Items = items;
                comp.Value = itemsData;
                comp.ItemsData = itemsData;

                evtData = matlab.ui.eventdata.ValueChangedData(...
                    comp.Value, previousValue);

                notify(comp, 'ValueChanged', evtData);
            end
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            if ~isequal(comp.BackgroundColor, comp.GridLayout.BackgroundColor)
                backgroundColor = comp.BackgroundColor;
                comp.GridLayout.BackgroundColor = backgroundColor;
                comp.IconButton.BackgroundColor = backgroundColor;
            end
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 283 23];
            comp.GridLayout = uigridlayout(comp);
            comp.Button = uibutton(comp.GridLayout, 'push');
            comp.Button.Text = '';
            comp.EditField = uieditfield(comp.GridLayout, 'text');
            comp.EditField.Editable = 'off';
            % Execute the startup function
            postSetupFcn(comp)
        end
    end
end