classdef ArrayEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        GridLayout                   matlab.ui.container.GridLayout
        FinishButtons                structeditor.component.FinishButtons
        InstanceSelectorPlaceholderLabel  matlab.ui.control.Label
        InteractiveListMenu          structeditor.component.InteractiveListMenu
        ControlPanel                 matlab.ui.container.Panel
        SelectmetadatainstanceLabel  matlab.ui.control.Label
    end

    properties (SetAccess = private)
        Data
    end

    properties
        NameField (1,1) string = missing
        NameFcn = []
        ItemType
        Title (1,1) string = "Edit Struct Array"
    end

    properties (Access = private)
        DefaultItem
        CurrentIdx = []
    end

    properties (Access = private)
        UIControlContainers (1,:) structeditor.UIControlContainer
    end

    % Dialog window properties.
    properties (SetAccess = private)
        FinishState (1,1) string {mustBeMember(FinishState, ["", "Finished", "Canceled"])} = ""
        CloseOnExit (1,1) logical = true
        MetadataCollection
    end

    properties (Access = private)
        % Todo: rename... Is true if uiwait is active...
        IsStandalone (1,1) logical = true
        InstanceDropDown
    end
    

    % Methods giving this app dialog like behavior.
    % Note: As it is not possible to include mixin classes or other
    % superclasses in an appdesigner app, this code is duplicated across
    % many apps. Unfortunate, but did not come up with a better solution
    % yet.
    methods
        function uiwait(app, preventClose)
            if nargin == 2
                app.CloseOnExit = ~preventClose;
            end
            app.IsStandalone = false;
            app.FinishState = "";
            uiwait(app.UIFigure) %#ok<ADMTHDINV>
        end
   
        function show(app)
            app.UIFigure.Visible = 'on';
        end

        function hide(app)
            app.UIFigure.Visible = 'off';
        end

        function tf = hasFigure(app)
            tf = isvalid(app.UIFigure);
        end
    end
    
    methods (Access = private)
        
        function onSelectedInstanceValueChanged(app, ~, evt)
            
            if isempty(evt.Value)
                data = app.DefaultItem();
            else
                newValue = evt.Value;
                data = om.convert.toStruct(newValue, app.MetadataCollection);
            end

            app.Data(app.CurrentIdx) = data;
            %app.UIControlContainers.Data = data;
            app.updateFormData(data)
        end

        function onSelectedItemChanged(app, ~, evt)
            if isempty(app.UIControlContainers)
                return
            end
            prevIdx = app.CurrentIdx;

            updatedData = app.UIControlContainers.Data;
            app.Data(prevIdx) = updatedData;

            data = app.Data(evt.NewIndex);
            %app.UIControlContainers.Data = data;
            app.updateFormData(data)

            app.CurrentIdx = evt.NewIndex;

            try
                app.InstanceDropDown.setValueFromId(data.id, false)
            catch ME
                disp(ME.message)
            end
        end

        function onItemAdded(app, ~, ~)

            if ~isempty(app.UIControlContainers)
                updatedData = app.UIControlContainers.Data;
                app.Data(app.CurrentIdx) = updatedData;
            else
                app.createForm()
                drawnow()
            end

            if app.InstanceDropDown.Enable == "off"
                app.InstanceDropDown.Enable = "on";
            end
            app.InstanceDropDown.resetSelection()

            % Todo: get selected type...
            if isempty(app.Data)
                app.Data = app.DefaultItem;
            else
                app.Data(end+1) = app.DefaultItem;
            end
            app.CurrentIdx = numel(app.Data);
            app.InteractiveListMenu.SelectedItemIndex = app.CurrentIdx;

            %app.UIControlContainers.Data = app.DefaultItem;
            app.updateFormData(app.DefaultItem)
        end
    
        function onItemRemoved(app, ~, ~)
            app.Data(app.CurrentIdx) = [];
            app.CurrentIdx = [];

            if isempty(app.Data)
                app.InstanceDropDown.Enable = "off";
                app.InstanceDropDown.resetSelection()
            end
        end

        % Callback methods related to dialog-like window 
        function onFinishedButtonPushed(app, ~, evt)
            
            app.FinishState = evt.FinishState;

            if app.FinishState == "Finished" % Update date
                app.updateDataFromForm()
            end
            
            app.close()
        end

        function onUIFigureCloseRequest(app, ~, ~)
            if app.CloseOnExit
                uiresume(app.UIFigure)
                drawnow
                pause(0.05)
                
                delete(app.UIControlContainers)
                delete(app.UIFigure)
            else
                uiresume(app.UIFigure)
            end
        end
    end

    methods
        function set.Title(app, value)
            app.Title = value;
            app.postSetTitle()
        end
    end
   
    methods (Access = private)
        function postSetTitle(app)
            app.UIFigure.Name = app.Title;
        end
    end

    methods (Access = private)
        function createForm(app, data)
            if nargin < 2
                data = app.DefaultItem;
            end
            H = structeditor.UIControlContainer(app.ControlPanel, data);
            app.UIControlContainers = H;
        end

        function updateDataFromForm(app)
        % updateDataFromForm - Update Data property based on info in current form
                   
            if isempty(app.Data); return; end

            if ~isempty(app.UIControlContainers)
                updatedData = app.UIControlContainers.Data;
                app.Data(app.CurrentIdx) = updatedData;
            end
        end

        function updateFormData(app, formData)
            % formType should be alias (short) form 
            if ~isempty(app.UIControlContainers)
                % Update values in form
                formType = app.ItemType;
                app.UIControlContainers.Data = formData;

                % Update enabled state
                enabledState = app.getEnabledStateForData(formType, formData);
                app.UIControlContainers.Enabled = enabledState;
                
                % Update editable state
                editableState = app.getEditableStateForType(formType);
                app.UIControlContainers.Editable = editableState;
            end
        end
                
        function editableState = getEditableStateForType(~, formType)
            if contains(formType, 'controlledterms') ...
                    && ~contains(formType, 'TermSuggestion')
                editableState = 'off';
            else
                editableState = 'on';
            end
        end

        function enabledState = getEnabledStateForData(~, formType, formData)
            if contains(formType, 'controlledterms') ...
                    && ~contains(formType, 'TermSuggestion') ...
                    && startsWith(formData.id, '_:') % Blank node identifier

                enabledState = 'off';
            else
                enabledState = 'on';
            end
        end
    end

    methods (Access = private)
        function close(app)
            app.onUIFigureCloseRequest()
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, options)
            arguments
                app
                data
                options.NameField (1,1) string = missing
                options.NameFcn = []
                options.ItemType (1,1) string = missing
                options.Title (1,1) string = "Edit Struct Array"
                options.DefaultItem = {}
                options.MetadataCollection
            end

            for iFieldName = string( fieldnames(options)' )
                app.(iFieldName) = options.(iFieldName);
            end

            uim.utility.centerFigureOnScreen(app.UIFigure) % Todo: do not use external function for this

            
            % Assign plugin component callbacks
            app.InteractiveListMenu.ItemSelectedFcn = @app.onSelectedItemChanged;
            app.InteractiveListMenu.ItemAddedFcn = @app.onItemAdded;
            app.InteractiveListMenu.ItemRemovedFcn = @app.onItemRemoved;

            app.FinishButtons.FinishButtonPushedFcn = @app.onFinishedButtonPushed;

            % Create InstanceDropDown
            app.InstanceDropDown = om.internal.control.InstanceDropDown(...
                app.GridLayout,...
                MetadataType=openminds.enum.Types(options.ItemType).ClassName, ...
                ActiveMetadataType=options.ItemType, ...
                MetadataCollection=app.MetadataCollection, ...
                ShowCreateAction=false);
            app.InstanceDropDown.ValueChangedFcn = @app.onSelectedInstanceValueChanged;

            app.InstanceDropDown.Layout.Row = 2;
            app.InstanceDropDown.Layout.Column = 2;

            app.Data = data;
            if isempty(app.DefaultItem)
                app.DefaultItem = structeditor.utility.resetStructData(data(1));
            end

            if ~ismissing(app.ItemType)
                app.InteractiveListMenu.ItemType = app.ItemType;
            end

            % Todo: Get labels.
            for i = 1:numel(app.Data)

                if ~ismissing(app.NameField)
                    itemName = app.Data(i).(app.NameField);
                elseif ~isempty(app.NameFcn)
                    itemName = app.NameFcn( app.Data(i) );
                else
                    itemName = '';
                end
                
                app.InteractiveListMenu.addItem(itemName)
            end

            if ~isempty(  app.Data )
                app.createForm( app.Data(1) )
                app.updateFormData(app.Data(1))
                app.CurrentIdx = 1;
                app.InteractiveListMenu.SelectedItemIndex = app.CurrentIdx;

                try
                    app.InstanceDropDown.setValueFromId(app.Data(1).id, false)
                catch ME
                    disp(ME.message)
                end
            else
                app.CurrentIdx = 0;
                app.InstanceDropDown.Enable = "off";
            end
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case 'backspace'
                    if isa(app.UIFigure.CurrentObject, 'matlab.ui.container.TreeNode')
                        app.InteractiveListMenu.removeItem( app.UIFigure.CurrentObject )
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectmetadatainstanceLabel = uilabel(app.GridLayout);
            app.SelectmetadatainstanceLabel.Text = 'Select metadata instance';
            app.ControlPanel = uipanel(app.GridLayout);
            app.InteractiveListMenu = structeditor.component.InteractiveListMenu(app.GridLayout);
            app.InstanceSelectorPlaceholderLabel = uilabel(app.GridLayout);
            app.InstanceSelectorPlaceholderLabel.Text = 'InstanceSelector goes here';
            app.FinishButtons = structeditor.component.FinishButtons(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ArrayEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end