classdef HeterogeneousArrayEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        FinishButtons                 structeditor.component.FinishButtons
        InstanceSelectorPlaceholderLabel  matlab.ui.control.Label
        TypeSelectorPlaceholderLabel  matlab.ui.control.Label
        InteractiveListMenu           structeditor.component.InteractiveListMenu
        SelectmetadatatypeLabel       matlab.ui.control.Label
        ControlPanel                  matlab.ui.container.Panel
        SelectmetadatainstanceLabel   matlab.ui.control.Label
    end

    properties (SetAccess = private)
        Data
    end

    properties
        NameField (1,1) string = missing
        NameFcn = []
        ItemType
        Title (1,1) string = "Edit Struct Array"
        IsScalar (1,1) logical = false
    end

    properties (Access = private)
        DefaultItem
        CurrentIdx = []
        ItemTypes
        ItemLabels
    end

    properties (Access = private)
        UIControlContainers (1,:) structeditor.UIControlContainer
        ContainerTypeMap (1,:) string
        StructTypeMap (1,:) string

        TypeSelector %om.internal.abstract.TypeSelector
    end

    % Dialog window properties.
    properties (SetAccess = private)
        FinishState (1,1) string {mustBeMember(FinishState, ["", "Finished", "Canceled"])} = ""
        CloseOnExit (1,1) logical = true
        MetadataCollection
        OpenMindsType
    end

    properties (Access = private)
        % Todo: rename... Is true if uiwait is active...
        IsStandalone (1,1) logical = true
        InstanceDropDown om.internal.control.InstanceDropDown
    end
    
    % Todo:
    %   [v] Work both with dropdown or radiobuttons for selecting type.
    %   [v] Populate dropdown with existing items...
    %   [ ] Add new instances to instance dropdown
    %   [ ] Dynamic update of labels if instances change.

    methods
        function set.Title(app, value)
            app.Title = value;
            app.postSetTitle()
        end
    end

    methods
        function [data, names] = getData(app)

            data = app.Data;
            names = cell(1,numel(data));

            for i = 1:numel(data)

                name = app.InteractiveListMenu.getItemName(i);
    
                % Define the regular expression pattern
                pattern = '^(?<name>.+?)(\s*\((?<annotation>.+?)\))?$';
                
                % Get current name and annotation
                tokens = regexp(name, pattern, 'names');
                names{i} =  tokens.name;
            end
        end
    end
    
    % Methods giving this app dialog like behavior
    methods
        function uiwait(app, preventClose)
            if nargin == 2
                app.CloseOnExit = ~preventClose;
            end
            app.IsStandalone = false;
            app.FinishState = "";
            uiwait(app.UIFigure) %#ok<ADMTHDINV>
        end
   
        function show(app)
            app.UIFigure.Visible = 'on';
        end

        function hide(app)
            app.UIFigure.Visible = 'off';
        end

        function tf = hasFigure(app)
            tf = isvalid(app.UIFigure);
        end
    end
    
    % Callback functions
    methods (Access = private)
        
        function onMetadataTypeSelectionChanged(app, ~, ~)
            app.changeSelectedType()
        end

        function onSelectedInstanceValueChanged(app, ~, evt)
            
            if isempty(evt.Value)
                data = app.getDefaultItemData();
            else
                newValue = evt.Value.Instance;
                data = om.convert.toStruct(newValue, app.MetadataCollection);
            end

            app.Data{app.CurrentIdx} = data;

            currentType = app.getCurrentType("alias");
            app.updateFormData(currentType, data);
        end

        function onSelectedItemChanged(app, ~, evt)

            prevIdx = app.CurrentIdx;

            if prevIdx == evt.NewIndex
                return
            end
            
            % Call drawnow to make sure value changed callbacks have time
            % to finish updating (might not be necessary)
            drawnow; pause(0.05)

            % Hide currently visible container
            if ~isempty(prevIdx)
                % Get data for current form and update Data
                app.updateDataFromForm()           
    
                previousType = app.StructTypeMap(prevIdx);
                prevContainerIdx = app.getContainerTypeIndex(previousType);
                app.UIControlContainers(prevContainerIdx).Visible = 'off';
            end

            % Update data for selected form
            data = app.Data{evt.NewIndex};
            
            newType = app.StructTypeMap(evt.NewIndex);
            newContainerIdx = app.getContainerTypeIndex(newType);
            if isempty(newContainerIdx)
                app.createForm(newType, data);
            else
                app.UIControlContainers(newContainerIdx).Visible = 'on';
            end

            % Update current index
            app.CurrentIdx = evt.NewIndex;

            % Update selected type in type selection component based on
            % type of selected item
            selectedType = app.StructTypeMap(app.CurrentIdx);

            app.updateFormData(selectedType, data)

            % Update "active" metadata type and dependent components
            currentTypeSelection = app.getCurrentType("alias");
            if ~isequal(currentTypeSelection, selectedType)
                app.TypeSelector.SelectedType = openminds.enum.Types.fromClassName(selectedType);
                app.InstanceDropDown.ActiveMetadataType = openminds.enum.Types.fromClassName(selectedType);
            end

            try
                if contains(selectedType, 'controlledterms') ...
                        && startsWith(data.id, '_:') ...
                        && ~contains(selectedType, 'TermSuggestion') % blank node % Todo: Should find a better way to determine this
                    app.InstanceDropDown.Value = feval(sprintf('%s.empty', selectedType));
                else
                    app.InstanceDropDown.setValueFromId(data.id, false)
                end
            catch ME
                disp(ME.message)
            end
        end

        function onItemAdded(app, ~, ~)

            % Get data for current form and update Data
            app.updateDataFromForm()

            % Get selected type as alias class name
            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                selectedType = app.getCurrentType("alias");
            else
                selectedType = string(missing);
            end

            if app.InstanceDropDown.Enable == "off"
                app.InstanceDropDown.Enable = "on";
            end
            app.InstanceDropDown.resetSelection()

            newStruct = app.getDefaultItemData();

            app.Data{end+1} = newStruct;
            app.CurrentIdx = numel(app.Data);
            app.StructTypeMap(app.CurrentIdx) = selectedType;
            
            if ~ismissing(selectedType)
                for i = 1:numel(app.UIControlContainers)
                    app.UIControlContainers(i).Visible = 'off';
                end
                if ~isempty( app.getContainerTypeIndex(selectedType) )
                    idx = app.getContainerTypeIndex(selectedType);
                    app.UIControlContainers(idx).Visible = 'on';
                else
                    app.createForm(selectedType, newStruct);
                end
            else
                error('Unexpected.')
                %app.UIControlContainers.Data = app.DefaultItem;
            end
            
            % Update container with uicontrols
            %app.UIControlContainers(idx).Data = newStruct;
            app.updateFormData(selectedType, newStruct);
            
            % Update listcontrol (add itemtype in parenthesis).
            typeName = app.getCurrentType("short");
            app.updateListItemName(app.CurrentIdx, typeName)
            app.InteractiveListMenu.SelectedItemIndex = app.CurrentIdx;
        end
        
        function onItemRemoved(app, ~, ~)
            
            app.Data(app.CurrentIdx) = [];
            
            previousType = app.StructTypeMap(app.CurrentIdx);
            app.StructTypeMap(app.CurrentIdx) = [];

            app.hideForm(previousType)
            app.CurrentIdx = [];

            if isempty(app.Data)
                app.InstanceDropDown.Enable = "off";
                app.InstanceDropDown.resetSelection()
            end

            return

            % Todo: Have a preference controlling whether to execute the
            % following:
            % Alternative: select next item in list
            nextIndex =  max( [1, app.CurrentIdx - 1] ); %#ok<UNRCH>
            if nextIndex > numel(app.Data)
                app.hideForm(previousType)
                app.CurrentIdx = [];

            else % Select new item...
                nextType = app.StructTypeMap(nextIndex);
                % Todo: make new method, should be called from
                % onItemSelected, onItemAdded and onItemRemoved

                if ~strcmp( nextType, previousType )
                    app.hideForm(previousType)
                    app.showForm(nextType)
                end
                app.updateFormData(nextType, app.Data{nextIndex})
                app.InteractiveListMenu.SelectedItemIndex = nextIndex;
                app.CurrentIdx = nextIndex;
            end
        end

        function onFinishedButtonPushed(app, ~, evt)
            
            app.FinishState = evt.FinishState;

            if app.FinishState == "Finished" % Update data
                app.updateDataFromForm()
            end
            
            app.close()
        end

        function onUIFigureCloseRequest(app, ~, ~)
            if app.CloseOnExit
                uiresume(app.UIFigure)
                drawnow
                pause(0.05)
                
                delete(app.UIControlContainers)
                delete(app.UIFigure)
            else
                uiresume(app.UIFigure)
            end
        end

        function hideForm(app, formType)
        %hideForm - Hide form for given form type
            formIndex = app.getContainerTypeIndex(formType);
            if ~isempty(formIndex)
                app.UIControlContainers(formIndex).Visible = 'off';
            end
        end

        function showForm(app, formType)
        %showForm - Show form for given form type
            formIndex = app.getContainerTypeIndex(formType);
            if ~isempty(formIndex)
                app.UIControlContainers(formIndex).Visible = 'on';
            end
        end

        function changeContainerType(app)
            
            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                selectedType = app.getCurrentType("alias");
            else
                selectedType = string(missing);
            end

            newData = app.getDefaultItemData();
            
            if ~ismissing(selectedType)
                for i = 1:numel(app.UIControlContainers)
                    app.UIControlContainers(i).Visible = 'off';
                end
                if ~isempty( app.getContainerTypeIndex(selectedType) )
                    idx = app.getContainerTypeIndex(selectedType);
                    app.UIControlContainers(idx).Visible = 'on';
                else
                    H = structeditor.UIControlContainer(app.ControlPanel, newData);
                    app.UIControlContainers(end+1) = H;
                    app.ContainerTypeMap(end+1) = selectedType;
                    idx = numel(app.UIControlContainers);
                end
            else
                error('Unexpected.')
                %app.UIControlContainers.Data = app.DefaultItem;
            end
        end

        function idx = getContainerTypeIndex(app, typeName)
            idx = find( strcmp(app.ContainerTypeMap, typeName) );
        end

        function createTypeSelector(app)
            % Select the type selector component type based on number of options
            if numel(app.DefaultItem.TypeNames) <= 4
                constructor = @om.internal.container.InstanceTypeButtonGroup;
            else
                constructor = @om.internal.container.InstanceTypeDropDown;
            end

            % Get types as enumeration objects.
            typeOptions = openminds.enum.Types.fromClassName(app.DefaultItem.TypeNames);

            % Create + configure the type selector component
            app.TypeSelector = constructor(app.GridLayout, 'Types', typeOptions);
            app.TypeSelector.Layout.Row = 2;
            app.TypeSelector.Layout.Column = 2;
            app.TypeSelector.SelectionChangedFcn = @app.onMetadataTypeSelectionChanged;
        end
    end

    methods
        function selectedType = getCurrentType(app, mode)
            
            if nargin < 2; mode = "full"; end

            selectedType = app.TypeSelector.SelectedType;
            
            % Format output
            if mode == "full"
                selectedType = selectedType.ClassName;
            elseif mode == "alias"
                selectedType = selectedType.AliasClassName;
            else
                selectedType = string(selectedType);
            end
        end

        function defaultItemData = getDefaultItemData(app, typeClassName)
            % Get instance type and default data
            
            if nargin < 2
                % NB: Need the alias class name because this is the one
                % used in the DefaultItem object
                typeClassName = app.getCurrentType("alias");
            end

            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                defaultItemData = app.DefaultItem.getType(typeClassName);
            else
                defaultItemData = app.DefaultItem;
            end
        end

        function changeSelectedType(app)

            if app.CurrentIdx >= 1 % One or more items are present

                currentType = app.StructTypeMap(app.CurrentIdx);

                % Check if form is dirty and open dialog.
                idx = app.getContainerTypeIndex(currentType);

                currentData = app.UIControlContainers(idx).Data;
                defaultData = app.getDefaultItemData(currentType);

                %if not( app.UIControlContainers(idx).IsClean() )
                if ~ isequaln(currentData, defaultData)
                    msg = "Note: Changing type will discard current changes.";
                    title = "Confirm change";
                    selection = uiconfirm(app.UIFigure, msg, title, ...
                        "Options",["Change Type","Cancel"], ...
                        "DefaultOption",1,"CancelOption",2);
                    if selection == "Cancel"
                        app.TypeSelector.SelectedType = openminds.enum.Types.fromClassName(currentType);   
                        return
                    end
                end
                
                % Get instance type and default data
                if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                    selectedType = app.getCurrentType("alias");
                else
                    selectedType = string(missing);
                end

                newData = app.getDefaultItemData();

                typeName = app.getCurrentType("short");
                app.updateListItemName(app.CurrentIdx, typeName)
            
                % Update instance data
                app.Data{app.CurrentIdx} = newData;

                % Update app.StructTypeMap
                app.StructTypeMap(app.CurrentIdx) = selectedType;

                % Change container and update container controls (form)
                app.changeContainerType()
                app.updateFormData(selectedType, newData)
          
                % Update instance dropdown
                app.InstanceDropDown.ActiveMetadataType = openminds.enum.Types.fromClassName(selectedType);

                try
                    app.InstanceDropDown.setValueFromId(newData.id, false)
                catch ME
                    disp(ME.message)
                end
            else
                % Update instance dropdown
                selectedType = app.getCurrentType("alias");
                app.InstanceDropDown.ActiveMetadataType = openminds.enum.Types.fromClassName(selectedType);
            end
        end

        function updateListItemName(app, listItemIndex, typeName)
            
            name = app.InteractiveListMenu.getItemName(listItemIndex);

            % Define the regular expression pattern
            pattern = '^(?<name>.+?)(\s*\((?<annotation>.+?)\))?$';
            
            % Get current name and annotation
            tokens = regexp(name, pattern, 'names');

            % Make sure type name is shortened
            selectedTypeSplit = strsplit(typeName, '.');
            typeName = selectedTypeSplit{end};

            name = app.createItemName(tokens.name, typeName);
            app.InteractiveListMenu.renameItem(listItemIndex, name)
        end
    end

    methods (Access = private)
        function postSetTitle(app)
            app.UIFigure.Name = app.Title;
        end
    end
    
    methods (Access = private)
        function updateDataFromForm(app)
        % updateDataFromForm - Update Data property based on info in current form
            
            if isempty(app.Data); return; end

            if ~isempty(app.UIControlContainers)
                currentType = app.StructTypeMap(app.CurrentIdx);
                currentContainerIdx = app.getContainerTypeIndex(currentType);
                updatedData = app.UIControlContainers(currentContainerIdx).Data;
                app.Data{app.CurrentIdx} = updatedData;
            end
        end

        function updateFormData(app, formType, formData)
            % formType should be alias (short) form 
            if ~isempty(app.UIControlContainers)
                % Update values in form
                formIdx = app.getContainerTypeIndex(formType);
                app.UIControlContainers(formIdx).Data = formData;

                % Update enabled state
                enabledState = app.getEnabledStateForData(formType, formData);
                app.UIControlContainers(formIdx).Enabled = enabledState;
                
                % Update editable state
                editableState = app.getEditableStateForType(formType);
                app.UIControlContainers(formIdx).Editable = editableState;
            end
        end
    
        function createForm(app, formType, formData)
            h = structeditor.UIControlContainer(app.ControlPanel, formData);
            app.UIControlContainers(end+1) = h;
            app.ContainerTypeMap(end+1) = formType;
        end
    
        function name = createItemName(~, name, type)
            name = sprintf('%s (%s)', name, type);
        end
    
        function editableState = getEditableStateForType(~, selectedType)
            if contains(selectedType, 'controlledterms') ...
                    && ~contains(selectedType, 'TermSuggestion')
                editableState = 'off';
            else
                editableState = 'on';
            end
        end

        function enabledState = getEnabledStateForData(~, formType, formData)
            if contains(formType, 'controlledterms') ...
                    && ~contains(formType, 'TermSuggestion') ...
                    && startsWith(formData.id, '_:') % Blank node identifier

                enabledState = 'off';
            else
                enabledState = 'on';
            end
        end
    end
    
    methods (Access = private)
        function close(app)
            app.onUIFigureCloseRequest()
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data, options)
            
            arguments
                app
                data
                options.NameField (1,1) string = missing
                options.NameFcn = []
                options.ItemType (1,1) string = missing
                options.Title (1,1) string = "Edit Struct Array"
                options.IsScalar (1,1) logical = false
                options.DefaultItem = {}
                options.MetadataCollection
                options.OpenMindsType

            end

            uim.utility.centerFigureOnScreen(app.UIFigure) % Todo: do not use external function for this

            for iFieldName = string( fieldnames(options)' )
                app.(iFieldName) = options.(iFieldName);
            end
            
            % Assign callbacks for InteractiveListMenu (left sidemenu) component
            app.InteractiveListMenu.ItemSelectedFcn = @app.onSelectedItemChanged;
            app.InteractiveListMenu.ItemAddedFcn = @app.onItemAdded;
            app.InteractiveListMenu.ItemRemovedFcn = @app.onItemRemoved;

            if app.IsScalar
                app.InteractiveListMenu.Visible = 'off';
                app.GridLayout.ColumnWidth = {0, '1x'};
                if isempty(data)
                    keyboard % Todo...
                end
            end
            
            app.FinishButtons.FinishButtonPushedFcn = @app.onFinishedButtonPushed;

            % Create instance dropdown
            app.InstanceDropDown = om.internal.control.InstanceDropDown(...
                app.GridLayout,...
                MetadataType=options.OpenMindsType, ...
                MetadataCollection=app.MetadataCollection, ...
                ShowCreateAction=false);
            app.InstanceDropDown.ValueChangedFcn = @app.onSelectedInstanceValueChanged;

            app.InstanceDropDown.Layout.Row = 4;
            app.InstanceDropDown.Layout.Column = 2;


            app.Data = data;
            if isempty(app.DefaultItem)
                app.DefaultItem = structeditor.utility.resetStructData(data(1));
            end

            if isa(app.DefaultItem, 'structeditor.TypedStructArray')
                if numel(app.DefaultItem.TypeNames) > 1
                    app.createTypeSelector()
                end
            end

            if ~ismissing(app.ItemType)
                app.InteractiveListMenu.ItemType = app.ItemType;
            end

            % Todo: Get labels in a better way.
            for i = 1:numel(app.Data)

                if ~ismissing(app.NameField)
                    itemName = app.Data{i}.(app.NameField);
                elseif ~isempty(app.NameFcn)
                    itemName = app.NameFcn( app.Data{i} );
                else
                    itemName = '';
                end

                app.InteractiveListMenu.addItem(itemName)
                
                %itemType = app.DefaultItem.getStructType(app.Data{i});
                %itemType = app.Data{i}.type;
                itemType = openminds.internal.utility.string.type2class( app.Data{i}.type );

                app.updateListItemName(i, itemType)
                selectedType = app.DefaultItem.getStructType(app.Data{i});
                app.StructTypeMap(i) = itemType; %selectedType;
            end

            if ~isempty( app.Data )

                H = structeditor.UIControlContainer(app.ControlPanel, app.Data{1});
                app.UIControlContainers = H;

                app.CurrentIdx = 1;
                %selectedType = app.DefaultItem.getStructType(app.Data{1});
                selectedType = openminds.internal.utility.string.type2class( app.Data{1}.type );
                app.ContainerTypeMap(1) = selectedType;
                app.InteractiveListMenu.SelectedItemIndex = app.CurrentIdx;
                
                app.TypeSelector.SelectedType = openminds.enum.Types.fromClassName(selectedType);
                app.InstanceDropDown.ActiveMetadataType = openminds.enum.Types.fromClassName(selectedType);

                editableState = app.getEditableStateForType(selectedType);
                app.UIControlContainers(1).Editable = editableState;

                try
                    app.InstanceDropDown.setValueFromId(app.Data{1}.id, false)
                catch ME
                    disp(ME.message)
                end
            else
                app.CurrentIdx = 0;
                app.InstanceDropDown.Enable = "off";
            end
        end

        % Callback function: UIFigure, UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;

            switch key
                case 'backspace'
                    if isa(app.UIFigure.CurrentObject, 'matlab.ui.container.TreeNode')
                        app.InteractiveListMenu.removeItem( app.UIFigure.CurrentObject )
                    end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.SelectmetadatainstanceLabel = uilabel(app.GridLayout);
            app.SelectmetadatainstanceLabel.Text = 'Select metadata instance';
            app.ControlPanel = uipanel(app.GridLayout);
            app.SelectmetadatatypeLabel = uilabel(app.GridLayout);
            app.SelectmetadatatypeLabel.Text = 'Select metadata type';
            app.InteractiveListMenu = structeditor.component.InteractiveListMenu(app.GridLayout);
            app.TypeSelectorPlaceholderLabel = uilabel(app.GridLayout);
            app.TypeSelectorPlaceholderLabel.Text = 'TypeSelector goes here';
            app.InstanceSelectorPlaceholderLabel = uilabel(app.GridLayout);
            app.InstanceSelectorPlaceholderLabel.Text = 'InstanceSelector goes here';
            app.FinishButtons = structeditor.component.FinishButtons(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HeterogeneousArrayEditor(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end